classdef Act3_Ejercicios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RacesdeEcuacionesLabel         matlab.ui.control.Label
        SebastinQuevedoA01722937Label  matlab.ui.control.Label
        Ejercicio4                     matlab.ui.control.Button
        Ejercicio3                     matlab.ui.control.Button
        Ejercicio2                     matlab.ui.control.Button
        Ejercicio1                     matlab.ui.control.Button
        Ejemplo                        matlab.ui.control.Button
        MtodoNewtonRaphsonLabel        matlab.ui.control.Label
        Actividad3Label                matlab.ui.control.Label
        Grafica                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Ejemplo
        function EjemploButtonPushed(app, event)
            syms x
            f = x^2 - 4*x + 2;
            a = -2:0.1:6;
            y = double(subs(f, a));
            fdx = diff(f);
            xi = 6;
            tolerancia = 1e-6;
            
            % Llamada de función newton raphson
            [raiz, xi, iter] = funcion_newton_raphson(f, fdx, xi, tolerancia);
            
            % Gráfica en la aplicación
            title(app.Grafica, ['El valor de x es = ' num2str(xi), '  Iteraciones = ' num2str(iter)])
            plot(app.Grafica, a, y, xi, raiz, 'or')
            xline(app.Grafica, 0)
            yline(app.Grafica, 0)
            grid(app.Grafica, "on")
            ylabel(app.Grafica, 'f(x) = x^2 - 4*x + 2')
            xlabel(app.Grafica, 'x')
        end

        % Button pushed function: Ejercicio1
        function Ejercicio1ButtonPushed(app, event)
            syms x
            f = (-1/10)*(x^2) + 3;
            a = -15:0.1:15;
            y = double(subs(f, a));
            fdx = diff(f);
            xi = 15;
            tolerancia = 1e-6;
            
            % Llamada de función newton raphson
            [raiz, xi, iter] = funcion_newton_raphson(f, fdx, xi, tolerancia);
            
            % Gráfica en la aplicación
            title(app.Grafica, ['El valor de x es = ' num2str(xi), '  Iteraciones = ' num2str(iter)])
            plot(app.Grafica, a, y, xi, raiz, 'or')
            xline(app.Grafica, 0)
            yline(app.Grafica, 0)
            grid(app.Grafica, "on")
            ylabel(app.Grafica, 'f(x) = (-1/10)*(x^2) + 3')
            xlabel(app.Grafica, 'x')
        end

        % Button pushed function: Ejercicio2
        function Ejercicio2ButtonPushed(app, event)
            syms x
            f = cos(x) - x^3;
            a = -3:0.1:4;
            y = double(subs(f, a));
            fdx = diff(f);
            xi = 4;
            tolerancia = 1e-6;
            
            % Llamada de función newton raphson
            [raiz, xi, iter] = funcion_newton_raphson(f, fdx, xi, tolerancia);
            
            % Gráfica en la aplicación
            title(app.Grafica, ['El valor de x es = ' num2str(xi), '  Iteraciones = ' num2str(iter)])
            plot(app.Grafica, a, y, xi, raiz, 'or')
            xline(app.Grafica, 0)
            yline(app.Grafica, 0)
            grid(app.Grafica, "on")
            ylabel(app.Grafica, 'f(x) = cos(x) - x^3')
            xlabel(app.Grafica, 'x')
        end

        % Button pushed function: Ejercicio3
        function Ejercicio3ButtonPushed(app, event)
            syms x
            f = x^3 + x + 16;
            a = -6:0.1:6;
            y = double(subs(f, a));
            fdx = diff(f);
            xi = 6;
            tolerancia = 1e-6;
            
            % Llamada de función newton raphson
            [raiz, xi, iter] = funcion_newton_raphson(f, fdx, xi, tolerancia);
            
            % Gráfica en la aplicación
            title(app.Grafica, ['El valor de x es = ' num2str(xi), '  Iteraciones = ' num2str(iter)])
            plot(app.Grafica, a, y, xi, raiz, 'or')
            xline(app.Grafica, 0)
            yline(app.Grafica, 0)
            grid(app.Grafica, "on")
            ylabel(app.Grafica, 'f(x) = x^3 + x + 16')
            xlabel(app.Grafica, 'x')
        end

        % Button pushed function: Ejercicio4
        function Ejercicio4ButtonPushed(app, event)
            syms x
            f = x^4 + 2*x - 1;
            a = -2:0.1:4;
            y = double(subs(f, a));
            fdx = diff(f);
            xi = 4;
            tolerancia = 1e-6;
            
            % Llamada de función newton raphson
            [raiz, xi, iter] = funcion_newton_raphson(f, fdx, xi, tolerancia);
            
            % Gráfica en la aplicación
            title(app.Grafica, ['El valor de x es = ' num2str(xi), '  Iteraciones = ' num2str(iter)])
            plot(app.Grafica, a, y, xi, raiz, 'or')
            xline(app.Grafica, 0)
            yline(app.Grafica, 0)
            grid(app.Grafica, "on")
            ylabel(app.Grafica, 'f(x) = x^4 + 2*x - 1')
            xlabel(app.Grafica, 'x')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Gráfica')
            xlabel(app.Grafica, 'x')
            ylabel(app.Grafica, 'f(x)')
            zlabel(app.Grafica, 'z')
            app.Actividad3Label = uilabel(app.UIFigure);
            app.Actividad3Label.Text = 'Actividad 3:';
            app.MtodoNewtonRaphsonLabel = uilabel(app.UIFigure);
            app.MtodoNewtonRaphsonLabel.Text = 'Método Newton-Raphson';
            app.Ejemplo = uibutton(app.UIFigure, 'push');
            app.Ejemplo.ButtonPushedFcn = createCallbackFcn(app, @EjemploButtonPushed, true);
            app.Ejemplo.Text = 'Ejemplo';
            app.Ejercicio1 = uibutton(app.UIFigure, 'push');
            app.Ejercicio1.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio1ButtonPushed, true);
            app.Ejercicio1.Text = 'Ejercicio 1';
            app.Ejercicio2 = uibutton(app.UIFigure, 'push');
            app.Ejercicio2.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio2ButtonPushed, true);
            app.Ejercicio2.Text = 'Ejercicio 2';
            app.Ejercicio3 = uibutton(app.UIFigure, 'push');
            app.Ejercicio3.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio3ButtonPushed, true);
            app.Ejercicio3.Text = 'Ejercicio 3';
            app.Ejercicio4 = uibutton(app.UIFigure, 'push');
            app.Ejercicio4.ButtonPushedFcn = createCallbackFcn(app, @Ejercicio4ButtonPushed, true);
            app.Ejercicio4.Text = 'Ejercicio 4';
            app.SebastinQuevedoA01722937Label = uilabel(app.UIFigure);
            app.SebastinQuevedoA01722937Label.Text = 'Sebastián Quevedo - A01722937';
            app.RacesdeEcuacionesLabel = uilabel(app.UIFigure);
            app.RacesdeEcuacionesLabel.Text = 'Raíces de Ecuaciones';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Act3_Ejercicios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end