classdef Act4_AppPolinomios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Limpiar                         matlab.ui.control.Button
        Graficar                        matlab.ui.control.Button
        SeleccionarGrafica              matlab.ui.container.ButtonGroup
        SelecGrafica4                   matlab.ui.control.RadioButton
        SelecGrafica3                   matlab.ui.control.RadioButton
        SelecGrafica2                   matlab.ui.control.RadioButton
        SelecGrafica1                   matlab.ui.control.RadioButton
        EncontrarRaiz                   matlab.ui.control.Button
        Raices                          matlab.ui.control.EditField
        RacesEditFieldLabel             matlab.ui.control.Label
        Polinomio                       matlab.ui.control.EditField
        VectorPolinomialEditFieldLabel  matlab.ui.control.Label
        Titulo                          matlab.ui.control.Label
        Grafica                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncontrarRaiz
        function EncontrarRaizButtonPushed(app, event)
            % Escanear y formatear (%f) usando el delimitador como la coma
            A = textscan(app.Polinomio.Value, '%f', 'Delimiter',',');

            % Convertir matriz de celdas a vector de números
            A = A{1};

            r = funcion_raices_polinomios(A); % Llamado a la función

            % Convertir el valor de app.Raices a las raices encontradas
            app.Raices.Value = num2str(r);
        end

        % Button pushed function: Graficar
        function GraficarButtonPushed(app, event)
            if (app.SelecGrafica1.Value)
                f = @(x) (x.^3 - 6*(x.^2) + 11.*x - 6);
                x = -1:0.1:5;
                plot(app.Grafica, x, f(x))
                xline(app.Grafica, 0)
                yline(app.Grafica, 0)
                xlabel(app.Grafica, 'x')
                ylabel(app.Grafica, 'f(x)')
                title(app.Grafica, 'Polinomio x.^3 - 6*(x.^2) + 11.*x - 6')
                grid(app.Grafica, 'on')
                hold(app.Grafica, 'on')
                
                A = [1 -6 11 -6]; % Coeficientes función
                r = funcion_raices_polinomios(A); % Llamado a la función
                
                % Evalua las raices con el polinomio
                a = f(r);
                
                % Graficar raices
                plot(app.Grafica, r, a, 'or')


            elseif (app.SelecGrafica2.Value)
                f = @(x) (x.^3 - x.^2 - (4.* x) + 4);
                x = -2:0.1:3;
                plot(app.Grafica, x, f(x))
                xline(app.Grafica, 0)
                yline(app.Grafica, 0)
                xlabel(app.Grafica, 'x')
                ylabel(app.Grafica, 'f(x)')
                title(app.Grafica, 'Polinomio x.^3 - x.^2 - (4.* x) + 4')
                grid(app.Grafica, 'on')
                hold(app.Grafica, 'on')
                
                A = [1 -1 -4 4]; % Coeficientes función
                r = funcion_raices_polinomios(A); % Llamado a la función
                
                % Evalua las raices con el polinomio
                a = f(r);
                
                % Graficar raices
                plot(app.Grafica, r, a, 'or')


            elseif (app.SelecGrafica3.Value)
                f = @(x) (-x.^4 + 10*(x.^2) - 9);
                x = -3:0.1:3;
                plot(app.Grafica, x, f(x))
                xline(app.Grafica, 0)
                yline(app.Grafica, 0)
                xlabel(app.Grafica, 'x')
                ylabel(app.Grafica, 'f(x)')
                title(app.Grafica, 'Polinomio -x.^4 + 10*(x.^2) - 9')
                grid(app.Grafica, 'on')
                hold(app.Grafica, 'on')
                
                A = [-1 10 -9]; % Coeficientes función
                r = funcion_raices_polinomios(A); % Llamado a la función
                
                % Evalua las raices con el polinomio
                a = f(r);
                
                % Graficar raices
                plot(app.Grafica, r, a, 'or')


            elseif (app.SelecGrafica4.Value)
                f = @(x) (2*(x.^3) + x.^2 - (13.* x) + 6);
                x = -4:0.1:4;
                plot(app.Grafica, x, f(x))
                xline(app.Grafica, 0)
                yline(app.Grafica, 0)
                xlabel(app.Grafica, 'x')
                ylabel(app.Grafica, 'f(x)')
                title(app.Grafica, '2*(x.^3) + x.^2 - (13.* x) + 6')
                grid(app.Grafica, 'on')
                hold(app.Grafica, 'on')
                
                A = [2 1 -13 6]; % Coeficientes función
                r = funcion_raices_polinomios(A); % Llamado a la función
                
                % Evalua las raices con el polinomio
                a = f(r);
                
                % Graficar raices
                plot(app.Grafica, r, a, 'or')
            end
        end

        % Button pushed function: Limpiar
        function LimpiarButtonPushed(app, event)
            cla(app.Grafica);
            title(app.Grafica, '')
            ylabel(app.Grafica, 'y')
            app.Polinomio.Value = '';
            app.Raices.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            xlabel(app.Grafica, 'x')
            ylabel(app.Grafica, 'y')
            zlabel(app.Grafica, 'z')
            app.Titulo = uilabel(app.UIFigure);
            app.Titulo.Text = 'Método Raíces Polinomios';
            app.VectorPolinomialEditFieldLabel = uilabel(app.UIFigure);
            app.VectorPolinomialEditFieldLabel.Text = 'Vector Polinomial';
            app.Polinomio = uieditfield(app.UIFigure, 'text');
            app.Polinomio.Placeholder = '-, -, -, -';
            app.RacesEditFieldLabel = uilabel(app.UIFigure);
            app.RacesEditFieldLabel.Text = 'Raíces';
            app.Raices = uieditfield(app.UIFigure, 'text');
            app.Raices.Placeholder = '- - - -';
            app.EncontrarRaiz = uibutton(app.UIFigure, 'push');
            app.EncontrarRaiz.ButtonPushedFcn = createCallbackFcn(app, @EncontrarRaizButtonPushed, true);
            app.EncontrarRaiz.Text = 'Encontrar Raíz';
            app.SeleccionarGrafica = uibuttongroup(app.UIFigure);
            app.SeleccionarGrafica.Title = 'Seleccionar Gráfica';
            app.SelecGrafica1 = uiradiobutton(app.SeleccionarGrafica);
            app.SelecGrafica1.Text = 'Gráfica 1';
            app.SelecGrafica1.Value = true;
            app.SelecGrafica2 = uiradiobutton(app.SeleccionarGrafica);
            app.SelecGrafica2.Text = 'Gráfica 2';
            app.SelecGrafica3 = uiradiobutton(app.SeleccionarGrafica);
            app.SelecGrafica3.Text = 'Gráfica 3';
            app.SelecGrafica4 = uiradiobutton(app.SeleccionarGrafica);
            app.SelecGrafica4.Text = 'Gráfica 4';
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Graficar.Text = 'Graficar';
            app.Limpiar = uibutton(app.UIFigure, 'push');
            app.Limpiar.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.Limpiar.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Act4_AppPolinomios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end