classdef CapacitanciaGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Switch                 matlab.ui.control.Switch
        Angulo                 matlab.ui.control.Slider
        ngulodelasplacasLabel  matlab.ui.control.Label
        Titulo2                matlab.ui.control.Label
        Titulo1                matlab.ui.control.Label
        Grafica                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        f = @(theta) ((((2*10 - 1)*(8.85*(10^-12))*(0.08^2)*(pi - theta)) / 0.01)*10^12);
        theta = 0:0.01:pi;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Angulo
        function AnguloValueChanged(app, event)
            angulo = app.Angulo.Value;
            plot(app.Grafica, angulo, app.f(angulo), 'or')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')    
                plot(app.Grafica, app.theta, app.f(app.theta))
                xline(app.Grafica, 0)
                yline(app.Grafica, 0)
                hold(app.Grafica, 'on')
            else
                plot(app.Grafica, 0, 0)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            xlabel(app.Grafica, 'Ángulo de rotación (radianes)')
            ylabel(app.Grafica, 'Capacitancia (pF)')
            zlabel(app.Grafica, 'z')
            app.Titulo1 = uilabel(app.UIFigure);
            app.Titulo1.Text = 'Gráfica de Capacitancia';
            app.Titulo2 = uilabel(app.UIFigure);
            app.Titulo2.Text = 'Ángulo de rotación como parámetro modificable';
            app.ngulodelasplacasLabel = uilabel(app.UIFigure);
            app.ngulodelasplacasLabel.Text = {'Ángulo de las'; 'placas (0-pi)'};
            app.Angulo = uislider(app.UIFigure);
            app.Angulo.Limits = [0 3.14159265358979];
            app.Angulo.ValueChangedFcn = createCallbackFcn(app, @AnguloValueChanged, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CapacitanciaGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end