classdef SwimmersGuide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Reset                matlab.ui.control.Button
        TotalTimeSec         matlab.ui.control.Label
        TotalTimeLabel2      matlab.ui.control.Label
        TotalLaps            matlab.ui.control.Label
        TotalLapsLabel       matlab.ui.control.Label
        TotalDistance        matlab.ui.control.Label
        TotalTimeMin         matlab.ui.control.Label
        TotalTimeminLabel    matlab.ui.control.Label
        TotalDistancemLabel  matlab.ui.control.Label
        PoolLength           matlab.ui.control.NumericEditField
        PoolLengthLabel      matlab.ui.control.Label
        Distance             matlab.ui.control.NumericEditField
        DistLabel            matlab.ui.control.Label
        Pace                 matlab.ui.control.NumericEditField
        PacesLabel           matlab.ui.control.Label
        Set                  matlab.ui.control.Button
        Table                matlab.ui.control.Table
        Title                matlab.ui.control.Label
        Graph                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ttlTime = 0;
        ttlTimeMin = 0;
        ttlTimeSec = 0;
        ttlDistance = 0;
        ttlLaps = 0;
        instance = 0;

        timeMin = 0;
        timeSec = 0;
        time = 0;

        timeList = [0];
        distanceList = [0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Set
        function SetButtonPushed(app, event)
            % Calculations
            app.timeMin = floor((app.Pace.Value * app.Distance.Value / 100) / 60);
            app.timeSec = rem((app.Pace.Value * app.Distance.Value / 100), 60);
            if app.timeSec >= 60
                app.timeMin = app.timeMin + floor(app.timeSec / 60);
                app.timeSec = rem(app.timeSec, 60);
            end
            app.ttlTimeMin = app.ttlTimeMin + app.timeMin;
            app.ttlTimeSec = app.ttlTimeSec + app.timeSec;

            app.time = (app.Pace.Value * app.Distance.Value / 100) / 60;
            app.ttlTime = app.ttlTime + app.time;

            app.ttlLaps = app.Distance.Value / app.PoolLength.Value;
            app.ttlDistance = app.ttlDistance + app.Distance.Value;
            
            
            % generate table % Add data to the Table UI Component
            app.Table.Data(end+1,:) = [app.Pace.Value' app.Distance.Value' app.time']; %create matrix of table data
            
            % Draw the values on the labels
            app.TotalDistance.Text = num2str(app.ttlDistance);
            drawnow
            app.TotalTimeMin.Text = num2str(app.ttlTimeMin);
            drawnow
            app.TotalTimeSec.Text = num2str(app.ttlTimeSec);
            drawnow
            app.TotalLaps.Text = num2str(app.ttlLaps);
            drawnow

            % Add time and distance values to list for lines to appear on the graph
            app.timeList(end+1) = app.ttlTime;
            app.distanceList(end+1) = app.ttlDistance;

            % plot graph
            plot(app.Graph, app.timeList, app.distanceList, 'Marker','o','LineWidth',2)
            
            xline(app.Graph, 0)
            yline(app.Graph, 0)
            grid(app.Graph, "on")
        end

        % Cell edit callback: Table
        function TableCellEdit(app, event)

        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.ttlTime = 0;
            app.ttlTimeMin = 0;
            app.ttlTimeSec = 0;
            app.ttlDistance = 0;
            app.ttlLaps = 0;
            app.instance = 0;

            app.timeList = [0];
            app.distanceList = [0];

            app.TotalDistance.Text = '0';
            drawnow
            app.TotalTimeMin.Text = '0';
            drawnow
            app.TotalTimeSec.Text = '0';
            drawnow
            app.TotalLaps.Text = '0';
            drawnow

            app.timeMin = 0;
            app.timeSec = 0;
            app.time = 0;

            app.Table.Data(:,:) = [];

            plot(app.Graph, 0, 0)
            xline(app.Graph, 0)
            yline(app.Graph, 0)
            grid(app.Graph, "on")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'Time')
            ylabel(app.Graph, 'Distance')
            zlabel(app.Graph, 'z')
            app.Graph.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Graph.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Swimmers Guide';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Pace'; 'Distance'; 'Time'};
            app.Table.RowName = {};
            app.Table.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.Set = uibutton(app.UIFigure, 'push');
            app.Set.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.Set.Text = 'Set';
            app.PacesLabel = uilabel(app.UIFigure);
            app.PacesLabel.Text = 'Pace (s)';
            app.Pace = uieditfield(app.UIFigure, 'numeric');
            app.DistLabel = uilabel(app.UIFigure);
            app.DistLabel.Text = 'Distance (m):';
            app.Distance = uieditfield(app.UIFigure, 'numeric');
            app.PoolLengthLabel = uilabel(app.UIFigure);
            app.PoolLengthLabel.Text = 'Pool Length (m)';
            app.PoolLength = uieditfield(app.UIFigure, 'numeric');
            app.TotalDistancemLabel = uilabel(app.UIFigure);
            app.TotalDistancemLabel.Text = 'Total Distance (m): ';
            app.TotalTimeminLabel = uilabel(app.UIFigure);
            app.TotalTimeminLabel.Text = 'Total Time (min): ';
            app.TotalTimeMin = uilabel(app.UIFigure);
            app.TotalTimeMin.Text = '';
            app.TotalDistance = uilabel(app.UIFigure);
            app.TotalDistance.Text = '';
            app.TotalLapsLabel = uilabel(app.UIFigure);
            app.TotalLapsLabel.Text = 'Total Laps: ';
            app.TotalLaps = uilabel(app.UIFigure);
            app.TotalLaps.Text = '';
            app.TotalTimeLabel2 = uilabel(app.UIFigure);
            app.TotalTimeLabel2.Text = ':';
            app.TotalTimeSec = uilabel(app.UIFigure);
            app.TotalTimeSec.Text = '';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwimmersGuide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end